classdef fileSelectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        UseforstringreplacementLabel  matlab.ui.control.Label
        EditFieldFileName             matlab.ui.control.EditField
        FileNameLabel                 matlab.ui.control.Label
        FileSelector                  wt.FileSelector
        MainFolderLabel               matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        OutputLabel                   matlab.ui.control.Label
        ClearOutputButton             matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        UITable                       matlab.ui.control.Table
        GridLayout2                   matlab.ui.container.GridLayout
        ReadspeFileButton             matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        UpdateTableButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % main app
        fileNum  % [1:instrument function, 2:spectra, 3:signal, 4:background]
    end
    
    methods (Access = private)
        function dispOut(app, text)
			newText = {">" + string(text)};
			currectText = string(app.TextArea.Value);
			app.TextArea.Value = [currectText; newText];
            scroll(app.TextArea, 'bottom')
        end

        function [updatedMatchingFiles, differences] = findMatchingFiles(app, searchString)
            filePath = app.FileSelector.Value;
            differences = {};
        
            % Check if the specified file path exists
            if ~isfolder(filePath)
                return;
            end
            
            % Get the list of files matching the search string in the directory
            fileList = dir(fullfile(filePath, searchString));
            
            % Extract the names of the matching files
            matchingFiles = {fileList.name};

            fileSubpath = app.getLeftHalfOfString(searchString);

            updatedMatchingFiles = app.addSubstringToStart(matchingFiles, fileSubpath);

            % Extract the pattern parts of the search string
            [startStr, endStr] = strtok(searchString, '*');
            endStr = endStr(2:end);  % Remove the '*' character
            
            % Find the differences between the filenames and the search pattern
            for ii = 1:length(updatedMatchingFiles)
                fileName = updatedMatchingFiles{ii};
                
                % Remove the start and end parts from the filename to get the difference
                difference = strrep(fileName, startStr, '');
                difference = strrep(difference, endStr, '');
                
                differences{ii} = difference;  % Store the difference
            end
        end
    
        function updateTable(app)
            if contains(app.EditFieldFileName.Value ,'*')
                app.asteriskModeUpdate()
            else
                app.fileExplorer()
            end

        end

        function asteriskModeUpdate(app)
            %fullFile = fullfile(app.FileSelector.Value, app.EditFieldFileName.Value);

            [matchingFiles, ~] = app.findMatchingFiles(app.EditFieldFileName.Value);
            
            tableData = matchingFiles';

            % Update the UITable with the combined data
            app.UITable.Data = tableData;
        end

        function fileExplorer(app)
            fullFile = fullfile(app.FileSelector.Value, app.EditFieldFileName.Value);
            if isfolder(fullFile)
                pathStr = fullFile;
            else
                % If it's a file, use fileparts to get the directory
                pathStr = fileparts(fullFile);
            end
            
            [matchingFiles, ~] = app.findMatchingFiles(pathStr);
            tableData = matchingFiles';

            % Update the UITable with the combined data
            app.UITable.Data = tableData;
        end
    
        function apply(app)
            app.mainapp.buttonArr(app.fileNum).Enable = 'on';

            fileSelected = app.UITable.DisplaySelection;
            if isempty(fileSelected)
                close all;
                delete(app)
                return;
            end

            if contains(app.EditFieldFileName.Value, '*')
                app.applyAsterisk(fileSelected)
            else
                app.applyFileExplorer(fileSelected)
            end
        end

        function applyAsterisk(app, fileSelected)
            app.mainapp.fileNameArr(app.fileNum).Value = app.EditFieldFileName.Value;
            [~, diff] = app.findMatchingFiles(app.EditFieldFileName.Value);
            app.mainapp.fileExpArr(app.fileNum).Value = diff{fileSelected};

            app.mainapp.updateFiles()
            close all;
            delete(app)
        end
    
        function applyFileExplorer(app, fileSelected)
            path = fileparts(app.EditFieldFileName.Value);
            selectedRow = app.UITable.Data{fileSelected};
            halfFile = fullfile(path, selectedRow);
            fullFile = fullfile(app.FileSelector.Value, halfFile);
            if ~isfolder(fullFile)
                app.mainapp.fileNameArr(app.fileNum).Value = halfFile;
                app.mainapp.fileExpArr(app.fileNum).Value = '';

                app.mainapp.updateFiles()
                close all;
                delete(app)
            else
                app.EditFieldFileName.Value = strcat(halfFile, '\');
                app.updateTable()
            end
        end
    end


    methods (Static)
        function leftHalf = getLeftHalfOfString(inputString)
            % Find the index of the last backslash in the string
            lastSlashIndex = find(inputString == '\', 1, 'last');
            
            % Extract the right half of the string, after the last backslash
            leftHalf = inputString(1:lastSlashIndex);
        end

        function updatedCellArray = addSubstringToStart(cellArray, substring)
            % Use cellfun to prepend the substring to each string in the cell array
            if ~isempty(substring)
                updatedCellArray = cellfun(@(x) [substring x], cellArray, 'UniformOutput', false);
            else
                updatedCellArray = cellArray;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, folder, file, fileNum)
            app.mainapp = mainapp;
            app.FileSelector.Value = folder;
            app.EditFieldFileName.Value = file;
            app.fileNum = fileNum;
            app.updateTable()
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            app.updateTable()
        end

        % Button pushed function: ClearOutputButton
        function ClearOutputButtonPushed(app, event)
            app.TextArea.Value = "";
        end

        % Button pushed function: ReadspeFileButton
        function ReadspeFileButtonPushed(app, event)
            tableData = app.UITable.DisplayData;
            
            fileSelected = app.UITable.DisplaySelection;
            if isempty(fileSelected)
                app.dispOut("No File Selected")
                return;
            end
            subFile = tableData{fileSelected, 1};
            filePath = fullfile(app.FileSelector.Value, subFile);
            
            [~, ~, ext] = fileparts(filePath);  % Get the file extension
            isSPE = strcmpi(ext, '.spe'); 
            if isSPE
                try
                    spe = ReadSPE(filePath, false);
                    app.dispOut("Plotting")
                    app.dispOut(filePath)
                    spe.drawRaman()
                catch
                    app.dispOut("File was not loaded correctly. The file may be corrupt")
                end
            else
                app.dispOut("File is not a .spe")
            end

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.apply()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.mainapp)
                app.mainapp.buttonArr(app.fileNum).Enable = 'on';
            end
            close all;
            delete(app)
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            %displayRow = event.InteractionInformation.DisplayRow;
            %displayColumn = event.InteractionInformation.DisplayColumn;
            app.apply()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UpdateTableButton = uibutton(app.GridLayout2, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ReadspeFileButton = uibutton(app.GridLayout2, 'push');
            app.ReadspeFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadspeFileButtonPushed, true);
            app.ReadspeFileButton.Text = 'Read .spe File';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.Multiselect = 'off';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.TextArea = uitextarea(app.GridLayout5);
            app.ClearOutputButton = uibutton(app.GridLayout5, 'push');
            app.ClearOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ClearOutputButtonPushed, true);
            app.ClearOutputButton.Text = 'Clear Output';
            app.OutputLabel = uilabel(app.GridLayout5);
            app.OutputLabel.Text = 'Output';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.MainFolderLabel = uilabel(app.GridLayout4);
            app.MainFolderLabel.Text = 'Main Folder';
            app.FileSelector = wt.FileSelector(app.GridLayout4);
            app.FileSelector.Value = "..\data";
            app.FileSelector.SelectionType = 'folder';
            app.FileSelector.History = "..\data";
            app.FileNameLabel = uilabel(app.GridLayout4);
            app.FileNameLabel.Text = 'File Name';
            app.EditFieldFileName = uieditfield(app.GridLayout4, 'text');
            app.EditFieldFileName.Value = '2020-07-21  *.spe';
            app.UseforstringreplacementLabel = uilabel(app.GridLayout4);
            app.UseforstringreplacementLabel.Text = 'Use ''*'' for string replacement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fileSelectorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end