classdef optimizationGUIV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes_overlay                  matlab.ui.control.UIAxes
        UIAxes_thomson                  matlab.ui.control.UIAxes
        UIAxes_landcape                 matlab.ui.control.UIAxes
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        RunOptionsTab                   matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        ContainerVolumeEditField        matlab.ui.control.NumericEditField
        ContainerVolumem3EditFieldLabel  matlab.ui.control.Label
        PressurePaEditField             matlab.ui.control.NumericEditField
        PressurePaEditFieldLabel        matlab.ui.control.Label
        LaserPowerWEditField            matlab.ui.control.NumericEditField
        LaserPowerWEditFieldLabel       matlab.ui.control.Label
        TempratureKEditField            matlab.ui.control.NumericEditField
        TempratureKEditFieldLabel       matlab.ui.control.Label
        PSOOptionsTab                   matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        IterationsforLandscapeEditField  matlab.ui.control.NumericEditField
        IterationsforLandscapeEditFieldLabel  matlab.ui.control.Label
        ParticlesforLandscapeEditField  matlab.ui.control.NumericEditField
        ParticlesforLandscapeEditFieldLabel  matlab.ui.control.Label
        IterationsforThomsonEditField   matlab.ui.control.NumericEditField
        IterationsforThomsonEditFieldLabel  matlab.ui.control.Label
        ParticlesforThomsonEditField    matlab.ui.control.NumericEditField
        ParticlesforThomsonEditFieldLabel  matlab.ui.control.Label
        OtherTab                        matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        VolumeCalculatorButton          matlab.ui.control.Button
        ShowPSOCostforThomsonButton     matlab.ui.control.Button
        ShowPSOCostforLandscapeButton   matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        Button_backgroundThomsonFile    matlab.ui.control.Button
        Button_signalThomsonFile        matlab.ui.control.Button
        Button_rawSpecFile              matlab.ui.control.Button
        Button_instaFile                matlab.ui.control.Button
        ExpEditField_instrFn            matlab.ui.control.EditField
        ExpEditField_4Label             matlab.ui.control.Label
        FileNameLabel                   matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        ExpirmentNumberLabel            matlab.ui.control.Label
        Lamp_Backgorund                 matlab.ui.control.Lamp
        Lamp_Signal                     matlab.ui.control.Lamp
        ExpEditField_backgroundTom      matlab.ui.control.EditField
        ExpEditField_3Label             matlab.ui.control.Label
        ExpEditField_signalTom          matlab.ui.control.EditField
        ExpEditField_2Label             matlab.ui.control.Label
        BackgroundThomsonEditField      matlab.ui.control.EditField
        BackgroundThomsonDataLabel      matlab.ui.control.Label
        SignalThomsonEditField          matlab.ui.control.EditField
        SignalThomsonEditFieldLabel     matlab.ui.control.Label
        Lamp_RawSpec                    matlab.ui.control.Lamp
        Lamp_InstFun                    matlab.ui.control.Lamp
        ExpEditField_rawSpec            matlab.ui.control.EditField
        ExpEditFieldLabel               matlab.ui.control.Label
        RawSpectraDataEditField         matlab.ui.control.EditField
        RawSpectraDataEditFieldLabel    matlab.ui.control.Label
        InstrumentFunctionEditField     matlab.ui.control.EditField
        InstrumentFunctionEditFieldLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        MainFolderLabel                 matlab.ui.control.Label
        FileSelector                    wt.FileSelector
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        ElectonVelocitymsEditField      matlab.ui.control.NumericEditField
        ElectonVelocitymsEditFieldLabel  matlab.ui.control.Label
        ElectonTemperatureeVEditField   matlab.ui.control.NumericEditField
        ElectonTemperatureeVEditFieldLabel  matlab.ui.control.Label
        ElectonDensityem3EditField      matlab.ui.control.NumericEditField
        ElectonDensityem3EditFieldLabel  matlab.ui.control.Label
        RunAllButton                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        RunThomsonButton                matlab.ui.control.Button
        RunSpecButton                   matlab.ui.control.Button
        ClearOutputButton               matlab.ui.control.Button
        OutputLabel                     matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        statusInstrumentFunction = 0
        statusRawSpec = 0
        statusSignalThomson = 0
        statusBackgroundThomson = 0

        instrumentFunctionFullPath
        rawSpecFileFullPath
        signalFileFullPath
        backgroundFileFullPath

        c1 = NaN
        area = NaN
        electronDensity = NaN
        electronTemperature = NaN
        electronDrift = NaN;

        opt  % Optimization Object
        tom  % Thomson Object
        volumeCalculatorApp  % matlab app
        fileApp
    end
    
    properties (Access = public)
        fileNameArr
        fileExpArr
        buttonArr
    end
    
    methods (Access = private)
        
		function dispOut(app, text)
			newText = {">" + string(text)};

			currectText = string(app.TextArea.Value);
			app.TextArea.Value = [currectText; newText];
            scroll(app.TextArea, 'bottom')
        end

        function verifyFiles(app)
            app.verifyInstrumentFunction()
            app.verifyRawSpec()
            app.verifySignalThomson()
            app.verifyBackgroundThomson()
            app.updateStatusLamp()
        end

        function verifyInstrumentFunction(app)
            % Instrument Function
            instrumentFunctionPath = strrep(app.InstrumentFunctionEditField.Value, '*', app.ExpEditField_instrFn.Value);
            instrumentFunctionFullPathNew = fullfile(app.FileSelector.Value, instrumentFunctionPath);

            % Initialize status to 0
            status = 0;
            
            %file = fullfile("instrument", app.InstrumentFunctionEditField.Value);
            file = app.InstrumentFunctionEditField.Value;
            matchingFiles = app.findMatchingFiles(file);
            validPath = ~isempty(matchingFiles);

            app.instrumentFunctionFullPath = instrumentFunctionFullPathNew;
            try
                instrumentTable = readtable(app.instrumentFunctionFullPath);
                instrumentTable.Wavelength;  % Wavelengths of instrument function
                instrumentTable.Intensity;  % This is used to check if Wavelength and Intensity values exist
                validTable = true;
            catch
                validTable = false;
            end
        
            if validTable
                status = 2;  % file path and expirment number is valid (green)
            end
            if validPath && ~validTable
                status = 1;  % file path is valid but incorrect expirment number (yellow)
            end
            if ~validPath
                status = 0;  % file path is not valid 
            end
            app.statusInstrumentFunction = status;
        end

        function [existingFullPath, status] = verifyFile(app, editFieldValue, expEditFieldValue, readSPEFlag)
            % Generate the file path
            filePath = strrep(editFieldValue, '*', expEditFieldValue);
            fullPathNew = fullfile(app.FileSelector.Value, filePath);
            
            % Initialize status to false
            status = 0;
            
            matchingFiles = app.findMatchingFiles(editFieldValue);
            validPath = ~isempty(matchingFiles);
            
            existingFullPath = fullPathNew;
            try
                ReadSPE(existingFullPath, readSPEFlag);
                validSPE = true;
            catch
                % If an error occurs, status remains false
                validSPE = false;
            end
          
            if validSPE
                status = 2;  % file path and expirment number is valid (green)
            end
            if validPath && ~validSPE
                status = 1;  % file path is valid but incorrect expirment number (yellow)
            end
            if ~validPath
                status = 0;  % file path is not valid 
            end
        end
        
        function verifyRawSpec(app)
            [app.rawSpecFileFullPath, app.statusRawSpec] = app.verifyFile(...
                app.RawSpectraDataEditField.Value, ...
                app.ExpEditField_rawSpec.Value, ...
                true);
        end
        
        function verifySignalThomson(app)
            [app.signalFileFullPath, app.statusSignalThomson] = app.verifyFile(...
                app.SignalThomsonEditField.Value, ...
                app.ExpEditField_signalTom.Value, ...
                false);
        end
        
        function verifyBackgroundThomson(app)
            [app.backgroundFileFullPath, app.statusBackgroundThomson] = app.verifyFile(...
                app.BackgroundThomsonEditField.Value, ...
                app.ExpEditField_backgroundTom.Value, ...
                false);
        end
        % Updates the visabilty of the status indicators
        function updateStatusLamp(app)
            statusintArr = [app.statusInstrumentFunction, app.statusRawSpec, app.statusSignalThomson, app.statusBackgroundThomson];
            lampArr = [app.Lamp_InstFun, app.Lamp_RawSpec, app.Lamp_Signal, app.Lamp_Backgorund];
            %buttonArr = [app.Button_instaFile, app.Button_rawSpecFile, app.Button_signalThomsonFile, app.Button_backgroundThomsonFile];
            for ii=1:length(lampArr)
                if statusintArr(ii) == 2
                    lampArr(ii).Color = [0, 1, 0];  % green
                    app.buttonArr(ii).Enable = "on";
                elseif statusintArr(ii) == 1
                    lampArr(ii).Color = [1, 1, 0];  % yellow
                    app.buttonArr(ii).Enable = "on";
                else
                    lampArr(ii).Color = [1, 0, 0];  % red
                    app.buttonArr(ii).Enable = "off";
                end
            end
        end

        function runSpec(app)
            if app.statusInstrumentFunction == 2 && app.statusRawSpec == 2
    
                app.dispOut("Running curve fitting")
                pause(0.01)
                
                % Energy in eV
                Bev = 2.48e-4;
                
                % Temperature in K
                environment.temperature = app.TempratureKEditField.Value;

                % Pressure in Pa
                environment.pressure = app.PressurePaEditField.Value;
                
                % Volume in m^3
                environment.volume = app.ContainerVolumeEditField.Value;

                % Power in W
                environment.power = app.LaserPowerWEditField.Value;

                % Incident light wavelength in nm
                centerWavelength = 532;

                % PSO Options
                psoOptions.numParticles = app.ParticlesforLandscapeEditField.Value;
                psoOptions.numIterations = app.IterationsforLandscapeEditField.Value;
                
                % Create Optimzation object
                app.opt = Optimize(app.rawSpecFileFullPath, app.instrumentFunctionFullPath, Bev, environment, centerWavelength, psoOptions);
    
                % perform curve fitting operation
                app.opt.optimize();
                
                % Draws Overlay
                app.opt.drawOverlay(app.UIAxes_overlay)
    
                % Draws Landscape
                app.opt.drawLandscape(app.UIAxes_landcape);

                % Update c1 and print
                app.c1 = app.opt.bestScale;
                c1Str = sprintf('c1=%.5e', app.c1);
                app.dispOut(c1Str)
            else
                app.c1 = NaN;
                app.dispOut("Unable to perform curve fitting")
                if ~app.statusInstrumentFunction == 2
                    app.dispOut("Instrument function not loaded")
                end
                if ~app.statusRawSpec == 2
                    app.dispOut("Spectra data not loaded")
                end
            end
            app.updateElectionDensity()
        end

        function runThomson(app)
            if app.statusBackgroundThomson == 2 && app.statusSignalThomson == 2
                app.dispOut("Running Thomson analysis")
                pause(0.01)
                
                % PSO Options
                psoOptions.numParticles = app.ParticlesforThomsonEditField.Value;
                psoOptions.numIterations = app.IterationsforThomsonEditField.Value;

                app.tom = Thomson(app.signalFileFullPath, app.backgroundFileFullPath, psoOptions);
                app.tom.drawThomson(app.UIAxes_thomson);

                % Update area and print
                app.area = app.tom.area_SI;
                %areaStr = sprintf('area=%.5e', app.area);
                %app.dispOut(areaStr)
            else
                app.area = NaN;
                app.dispOut("Unable to perform thomson analysis")
                if ~app.statusSignalThomson == 2
                    app.dispOut("Signal thomson not loaded")
                end
                if ~app.statusBackgroundThomson == 2
                    app.dispOut("Background thomson not loaded")
                end
            end
            app.updateElectionDensity()
        end

        function updateElectionDensity(app)
            if ~isnan(app.c1) && ~isnan(app.area)
                app.electronDensity = app.c1 * app.area;
                electronDensityStr = sprintf('%.5e', app.electronDensity);
                app.ElectonDensityem3EditField.Value = app.electronDensity;
                app.dispOut("Electron Density=" + electronDensityStr)
                
                app.electronTemperature = app.tom.temperature;
                electronTemperatureStr = sprintf('%.5e', app.electronTemperature);
                app.ElectonTemperatureeVEditField.Value = app.electronTemperature;
                app.dispOut("Electron Temperature=" + electronTemperatureStr);

                app.electronDrift = app.tom.drift;
                electronDriftStr = sprintf('%.5e', app.electronDrift);
                app.ElectonVelocitymsEditField.Value = app.electronDrift;
                app.dispOut("Electron Drift=" + electronDriftStr);
            else
                app.ElectonDensityem3EditField.Value = 0;
                app.ElectonTemperatureeVEditField.Value = 0;
                app.ElectonVelocitymsEditField.Value = 0;
            end
        end

        function clearAxis(app)
            cla(app.UIAxes_overlay)
            cla(app.UIAxes_landcape)
            cla(app.UIAxes_thomson)
            app.electronDensity = NaN;
            app.c1 = NaN;
            app.area = NaN;
            app.opt = double.empty;
            app.tom = double.empty;
            app.updateElectionDensity()
        end

        function saveEditFields(app)
            saveData = struct();

            saveData.FileSelector = app.FileSelector.Value;

            saveData.InstrumentFunction = app.InstrumentFunctionEditField.Value;
            saveData.RawSpectraData = app.RawSpectraDataEditField.Value;
            saveData.SignalThomson = app.SignalThomsonEditField.Value;
            saveData.BackgroundThomson = app.BackgroundThomsonEditField.Value;

            saveData.ExpEditField_instrFn = app.ExpEditField_instrFn.Value;
            saveData.ExpEditField_rawSpec = app.ExpEditField_rawSpec.Value;
            saveData.ExpEditField_signalTom = app.ExpEditField_signalTom.Value;
            saveData.ExpEditField_backgroundTom = app.ExpEditField_backgroundTom.Value;

            saveData.Temprature = app.TempratureKEditField.Value;
            saveData.LaserPower = app.LaserPowerWEditField.Value;
            saveData.Pressur = app.PressurePaEditField.Value;
            saveData.Volume = app.ContainerVolumeEditField.Value;

            saveData.ParticlesforLandscape = app.ParticlesforLandscapeEditField.Value;
            saveData.IterationsforLandscape = app.IterationsforLandscapeEditField.Value;
            saveData.ParticlesforThomson = app.ParticlesforThomsonEditField.Value;
            saveData.IterationsforThomson = app.IterationsforThomsonEditField.Value;

            savePath = fullfile(getenv('HOME'), 'autosave.mat'); % Note the addition of the '.mat' extension

            save(savePath, 'saveData');
        end

        function loadEditFields(app)
            loadFilePath = fullfile(getenv('HOME'), 'autosave.mat');
    
            % Check if the file exists
            if isfile(loadFilePath)
                % Load the saved data
                loadedData = load(loadFilePath).saveData;

                % Restore the saved data to the UI components
                app.FileSelector.Value = loadedData.FileSelector;

                app.InstrumentFunctionEditField.Value = loadedData.InstrumentFunction;
                app.RawSpectraDataEditField.Value = loadedData.RawSpectraData;
                app.SignalThomsonEditField.Value = loadedData.SignalThomson;
                app.BackgroundThomsonEditField.Value = loadedData.BackgroundThomson;

                app.ExpEditField_instrFn.Value = loadedData.ExpEditField_instrFn;
                app.ExpEditField_rawSpec.Value = loadedData.ExpEditField_rawSpec;
                app.ExpEditField_signalTom.Value = loadedData.ExpEditField_signalTom;
                app.ExpEditField_backgroundTom.Value = loadedData.ExpEditField_backgroundTom;

                app.TempratureKEditField.Value = loadedData.Temprature;
                app.LaserPowerWEditField.Value = loadedData.LaserPower;
                app.PressurePaEditField.Value = loadedData.Pressur;
                app.ContainerVolumeEditField.Value = loadedData.Volume;
        
                app.ParticlesforLandscapeEditField.Value = loadedData.ParticlesforLandscape;
                app.IterationsforLandscapeEditField.Value = loadedData.IterationsforLandscape;
                app.ParticlesforThomsonEditField.Value = loadedData.ParticlesforThomson;
                app.IterationsforThomsonEditField.Value = loadedData.IterationsforThomson;
            end
        end

        function setFileLocation(app)
            % Get the full path of the currently running script or function
            currentFilePath = mfilename('fullpath');
            
            % Extract the directory part of the file path
            currentFileDir = fileparts(currentFilePath);
            
            % Change the current directory to that directory
            cd(currentFileDir);
            
            % Display the current directory for verification
            app.dispOut(['Current directory set to: ', currentFileDir]);
        end

        function matchingFiles = findMatchingFiles(app, searchString)
            filePath = app.FileSelector.Value;
            matchingFiles = double.empty;

            % Check if the specified file path exists
            if ~isfolder(filePath)
                return;
            end
            
            % Get the list of files matching the search string in the directory
            fileList = dir(fullfile(filePath, searchString));
            
            % Extract the names of the matching files
            matchingFiles = {fileList.name};
        end

        function updateArrVars(app)
            app.fileNameArr = [app.InstrumentFunctionEditField,...
                app.RawSpectraDataEditField,...
                app.SignalThomsonEditField,...
                app.BackgroundThomsonEditField];
    
            app.fileExpArr = [app.ExpEditField_instrFn,...
                app.ExpEditField_rawSpec,...
                app.ExpEditField_signalTom,...
                app.ExpEditField_backgroundTom];

            app.buttonArr = [app.Button_instaFile,...
                app.Button_rawSpecFile,...
                app.Button_signalThomsonFile,...
                app.Button_backgroundThomsonFile];
        end
    end
    
    methods (Access = public)
        function updateVolume(app, newVolume)
            app.ContainerVolumeEditField.Value = str2double(newVolume);
        end

        function updateFiles(app)
            app.verifyFiles()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startUpFun(app)
            app.updateArrVars()
            
            rng(1);
            app.setFileLocation()
            app.loadEditFields()
            app.verifyFiles()
        end

        % Button pushed function: RunSpecButton
        function RunSpecButtonPushed(app, event)
            app.verifyFiles()
            cla(app.UIAxes_overlay)
            cla(app.UIAxes_landcape)
            app.runSpec()
        end

        % Button pushed function: RunAllButton
        function runAll(app, event)
            app.verifyFiles()
            app.clearAxis()
            app.runSpec()
            app.runThomson()
        end

        % Button pushed function: ClearOutputButton
        function clearOutput(app, event)
            app.TextArea.Value = "";
        end

        % Button pushed function: RunThomsonButton
        function RunThomsonButtonPushed(app, event)
            app.verifyFiles()
            cla(app.UIAxes_thomson)
            app.runThomson()
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.clearAxis()
        end

        % Value changed function: InstrumentFunctionEditField
        function InstrumentFunctionEditFieldValueChanged(app, event)
            app.verifyInstrumentFunction()
            app.updateStatusLamp()
        end

        % Value changed function: ExpEditField_instrFn
        function ExpEditField_instrFnValueChanged(app, event)
            app.verifyInstrumentFunction()
            app.updateStatusLamp()
        end

        % Value changed function: RawSpectraDataEditField
        function RawSpectraDataEditFieldValueChanged(app, event)
            app.verifyRawSpec()
            app.updateStatusLamp()
        end

        % Value changed function: ExpEditField_rawSpec
        function ExpEditField_rawSpecValueChanged(app, event)
            app.verifyRawSpec()
            app.updateStatusLamp()
        end

        % Value changed function: SignalThomsonEditField
        function SignalThomsonEditFieldValueChanged(app, event)
            app.verifySignalThomson()
            app.updateStatusLamp()
        end

        % Value changed function: ExpEditField_signalTom
        function ExpEditField_signalTomValueChanged(app, event)
            app.verifySignalThomson()
            app.updateStatusLamp()
        end

        % Value changed function: BackgroundThomsonEditField
        function BackgroundThomsonEditFieldValueChanged(app, event)
            app.verifyBackgroundThomson()
            app.updateStatusLamp()
        end

        % Value changed function: ExpEditField_backgroundTom
        function ExpEditField_backgroundTomValueChanged(app, event)
            app.verifyBackgroundThomson()
            app.updateStatusLamp()
        end

        % Button pushed function: ShowPSOCostforLandscapeButton
        function ShowPSOCostforLandscapeButtonPushed(app, event)
            if ~isempty(app.opt)
                app.opt.pso.drawCostCurve()
            end
        end

        % Button pushed function: ShowPSOCostforThomsonButton
        function ShowPSOCostforThomsonButtonPushed(app, event)
            if ~isempty(app.tom)
                app.tom.pso.drawCostCurve()
            end
        end

        % Button pushed function: VolumeCalculatorButton
        function VolumeCalculatorButtonPushed(app, event)
            app.volumeCalculatorApp = volumeCalculator(app);
            app.VolumeCalculatorButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.volumeCalculatorApp)
            delete(app.fileApp)
            app.saveEditFields()
            close all;
            delete(app)
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            app.verifyFiles()
        end

        % Button pushed function: Button_instaFile
        function Button_instaFilePushed(app, event)
            app.fileApp = fileSelectorApp(app, app.FileSelector.Value, app.InstrumentFunctionEditField.Value, 1);
            app.Button_instaFile.Enable = 'off';
        end

        % Button pushed function: Button_rawSpecFile
        function Button_rawSpecFilePushed(app, event)
            app.fileApp = fileSelectorApp(app, app.FileSelector.Value, app.RawSpectraDataEditField.Value, 2);
            app.Button_rawSpecFile.Enable = 'off';
        end

        % Button pushed function: Button_signalThomsonFile
        function Button_signalThomsonFilePushed(app, event)
            app.fileApp = fileSelectorApp(app, app.FileSelector.Value, app.SignalThomsonEditField.Value, 3);
            app.Button_signalThomsonFile.Enable = 'off';
        end

        % Button pushed function: Button_backgroundThomsonFile
        function Button_backgroundThomsonFilePushed(app, event)
            app.fileApp = fileSelectorApp(app, app.FileSelector.Value, app.BackgroundThomsonEditField.Value, 4);
            app.Button_backgroundThomsonFile.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TextArea = uitextarea(app.GridLayout2);
            app.OutputLabel = uilabel(app.GridLayout2);
            app.OutputLabel.Text = 'Output';
            app.ClearOutputButton = uibutton(app.GridLayout2, 'push');
            app.ClearOutputButton.ButtonPushedFcn = createCallbackFcn(app, @clearOutput, true);
            app.ClearOutputButton.Text = 'Clear Output';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.RunSpecButton = uibutton(app.GridLayout8, 'push');
            app.RunSpecButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpecButtonPushed, true);
            app.RunSpecButton.Text = 'Run Spec';
            app.RunThomsonButton = uibutton(app.GridLayout8, 'push');
            app.RunThomsonButton.ButtonPushedFcn = createCallbackFcn(app, @RunThomsonButtonPushed, true);
            app.RunThomsonButton.Text = 'Run Thomson';
            app.ClearButton = uibutton(app.GridLayout8, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RunAllButton = uibutton(app.GridLayout8, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @runAll, true);
            app.RunAllButton.Text = 'Run All';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.ElectonDensityem3EditFieldLabel = uilabel(app.GridLayout10);
            app.ElectonDensityem3EditFieldLabel.Text = 'Electon Density (e/m^3)';
            app.ElectonDensityem3EditField = uieditfield(app.GridLayout10, 'numeric');
            app.ElectonDensityem3EditField.Editable = 'off';
            app.ElectonTemperatureeVEditFieldLabel = uilabel(app.GridLayout10);
            app.ElectonTemperatureeVEditFieldLabel.Text = 'Electon Temperature (eV)';
            app.ElectonTemperatureeVEditField = uieditfield(app.GridLayout10, 'numeric');
            app.ElectonTemperatureeVEditField.Editable = 'off';
            app.ElectonVelocitymsEditFieldLabel = uilabel(app.GridLayout10);
            app.ElectonVelocitymsEditFieldLabel.Text = 'Electon Velocity (m/s)';
            app.ElectonVelocitymsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.ElectonVelocitymsEditField.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.FileSelector = wt.FileSelector(app.GridLayout6);
            app.FileSelector.SelectionType = 'folder';
            app.FileSelector.History = "C:\Users\david\OneDrive\School\Plasma Research\optimization\GUI\data";
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.MainFolderLabel = uilabel(app.GridLayout6);
            app.MainFolderLabel.Text = 'Main Folder';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.InstrumentFunctionEditFieldLabel = uilabel(app.GridLayout7);
            app.InstrumentFunctionEditFieldLabel.Text = 'Instrument Function';
            app.InstrumentFunctionEditField = uieditfield(app.GridLayout7, 'text');
            app.InstrumentFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @InstrumentFunctionEditFieldValueChanged, true);
            app.RawSpectraDataEditFieldLabel = uilabel(app.GridLayout7);
            app.RawSpectraDataEditFieldLabel.Text = 'Raw Spectra Data';
            app.RawSpectraDataEditField = uieditfield(app.GridLayout7, 'text');
            app.RawSpectraDataEditField.ValueChangedFcn = createCallbackFcn(app, @RawSpectraDataEditFieldValueChanged, true);
            app.ExpEditFieldLabel = uilabel(app.GridLayout7);
            app.ExpEditFieldLabel.Text = 'Exp. #';
            app.ExpEditField_rawSpec = uieditfield(app.GridLayout7, 'text');
            app.ExpEditField_rawSpec.ValueChangedFcn = createCallbackFcn(app, @ExpEditField_rawSpecValueChanged, true);
            app.Lamp_InstFun = uilamp(app.GridLayout7);
            app.Lamp_RawSpec = uilamp(app.GridLayout7);
            app.SignalThomsonEditFieldLabel = uilabel(app.GridLayout7);
            app.SignalThomsonEditFieldLabel.Text = 'Signal Thomson';
            app.SignalThomsonEditField = uieditfield(app.GridLayout7, 'text');
            app.SignalThomsonEditField.ValueChangedFcn = createCallbackFcn(app, @SignalThomsonEditFieldValueChanged, true);
            app.BackgroundThomsonDataLabel = uilabel(app.GridLayout7);
            app.BackgroundThomsonDataLabel.Text = 'Background Thomson';
            app.BackgroundThomsonEditField = uieditfield(app.GridLayout7, 'text');
            app.BackgroundThomsonEditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundThomsonEditFieldValueChanged, true);
            app.ExpEditField_2Label = uilabel(app.GridLayout7);
            app.ExpEditField_2Label.Text = 'Exp. #';
            app.ExpEditField_signalTom = uieditfield(app.GridLayout7, 'text');
            app.ExpEditField_signalTom.ValueChangedFcn = createCallbackFcn(app, @ExpEditField_signalTomValueChanged, true);
            app.ExpEditField_3Label = uilabel(app.GridLayout7);
            app.ExpEditField_3Label.Text = 'Exp. #';
            app.ExpEditField_backgroundTom = uieditfield(app.GridLayout7, 'text');
            app.ExpEditField_backgroundTom.ValueChangedFcn = createCallbackFcn(app, @ExpEditField_backgroundTomValueChanged, true);
            app.Lamp_Signal = uilamp(app.GridLayout7);
            app.Lamp_Backgorund = uilamp(app.GridLayout7);
            app.ExpirmentNumberLabel = uilabel(app.GridLayout7);
            app.ExpirmentNumberLabel.Text = 'Expirment Number';
            app.StatusLabel = uilabel(app.GridLayout7);
            app.StatusLabel.Text = 'Status';
            app.FileNameLabel = uilabel(app.GridLayout7);
            app.FileNameLabel.Text = 'File Name';
            app.ExpEditField_4Label = uilabel(app.GridLayout7);
            app.ExpEditField_4Label.Text = 'Exp. #';
            app.ExpEditField_instrFn = uieditfield(app.GridLayout7, 'text');
            app.ExpEditField_instrFn.ValueChangedFcn = createCallbackFcn(app, @ExpEditField_instrFnValueChanged, true);
            app.Button_instaFile = uibutton(app.GridLayout7, 'push');
            app.Button_instaFile.ButtonPushedFcn = createCallbackFcn(app, @Button_instaFilePushed, true);
            app.Button_instaFile.Icon = fullfile(pathToMLAPP, 'icon', 'Icons8_flat_folder.svg.png');
            app.Button_instaFile.Text = '';
            app.Button_rawSpecFile = uibutton(app.GridLayout7, 'push');
            app.Button_rawSpecFile.ButtonPushedFcn = createCallbackFcn(app, @Button_rawSpecFilePushed, true);
            app.Button_rawSpecFile.Icon = fullfile(pathToMLAPP, 'icon', 'Icons8_flat_folder.svg.png');
            app.Button_rawSpecFile.Text = '';
            app.Button_signalThomsonFile = uibutton(app.GridLayout7, 'push');
            app.Button_signalThomsonFile.ButtonPushedFcn = createCallbackFcn(app, @Button_signalThomsonFilePushed, true);
            app.Button_signalThomsonFile.Icon = fullfile(pathToMLAPP, 'icon', 'Icons8_flat_folder.svg.png');
            app.Button_signalThomsonFile.Text = '';
            app.Button_backgroundThomsonFile = uibutton(app.GridLayout7, 'push');
            app.Button_backgroundThomsonFile.ButtonPushedFcn = createCallbackFcn(app, @Button_backgroundThomsonFilePushed, true);
            app.Button_backgroundThomsonFile.Icon = fullfile(pathToMLAPP, 'icon', 'Icons8_flat_folder.svg.png');
            app.Button_backgroundThomsonFile.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.RunOptionsTab = uitab(app.TabGroup);
            app.RunOptionsTab.Title = 'Run Options';
            app.GridLayout9 = uigridlayout(app.RunOptionsTab);
            app.TempratureKEditFieldLabel = uilabel(app.GridLayout9);
            app.TempratureKEditFieldLabel.Text = 'Temprature (K)';
            app.TempratureKEditField = uieditfield(app.GridLayout9, 'numeric');
            app.TempratureKEditField.Value = 288;
            app.LaserPowerWEditFieldLabel = uilabel(app.GridLayout9);
            app.LaserPowerWEditFieldLabel.Text = 'Laser Power (W)';
            app.LaserPowerWEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LaserPowerWEditField.Value = 4;
            app.PressurePaEditFieldLabel = uilabel(app.GridLayout9);
            app.PressurePaEditFieldLabel.Text = 'Pressure (Pa)';
            app.PressurePaEditField = uieditfield(app.GridLayout9, 'numeric');
            app.PressurePaEditField.Value = 1000;
            app.ContainerVolumem3EditFieldLabel = uilabel(app.GridLayout9);
            app.ContainerVolumem3EditFieldLabel.Text = 'Container Volume (m^3)';
            app.ContainerVolumeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ContainerVolumeEditField.Value = 0.90478;
            app.PSOOptionsTab = uitab(app.TabGroup);
            app.PSOOptionsTab.Title = 'PSO Options';
            app.GridLayout11 = uigridlayout(app.PSOOptionsTab);
            app.ParticlesforThomsonEditFieldLabel = uilabel(app.GridLayout11);
            app.ParticlesforThomsonEditFieldLabel.Text = '# Particles for Thomson';
            app.ParticlesforThomsonEditField = uieditfield(app.GridLayout11, 'numeric');
            app.ParticlesforThomsonEditField.Value = 400;
            app.IterationsforThomsonEditFieldLabel = uilabel(app.GridLayout11);
            app.IterationsforThomsonEditFieldLabel.Text = '# Iterations for Thomson';
            app.IterationsforThomsonEditField = uieditfield(app.GridLayout11, 'numeric');
            app.IterationsforThomsonEditField.Value = 40;
            app.ParticlesforLandscapeEditFieldLabel = uilabel(app.GridLayout11);
            app.ParticlesforLandscapeEditFieldLabel.Text = '# Particles for Landscape';
            app.ParticlesforLandscapeEditField = uieditfield(app.GridLayout11, 'numeric');
            app.ParticlesforLandscapeEditField.Value = 400;
            app.IterationsforLandscapeEditFieldLabel = uilabel(app.GridLayout11);
            app.IterationsforLandscapeEditFieldLabel.Text = '# Iterations for Landscape';
            app.IterationsforLandscapeEditField = uieditfield(app.GridLayout11, 'numeric');
            app.IterationsforLandscapeEditField.Value = 40;
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.GridLayout12 = uigridlayout(app.OtherTab);
            app.ShowPSOCostforLandscapeButton = uibutton(app.GridLayout12, 'push');
            app.ShowPSOCostforLandscapeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPSOCostforLandscapeButtonPushed, true);
            app.ShowPSOCostforLandscapeButton.Text = {'Show PSO Cost'; 'for Landscape'};
            app.ShowPSOCostforThomsonButton = uibutton(app.GridLayout12, 'push');
            app.ShowPSOCostforThomsonButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPSOCostforThomsonButtonPushed, true);
            app.ShowPSOCostforThomsonButton.Text = {'Show PSO Cost'; 'for Thomson'};
            app.VolumeCalculatorButton = uibutton(app.GridLayout12, 'push');
            app.VolumeCalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeCalculatorButtonPushed, true);
            app.VolumeCalculatorButton.Text = 'Volume Calculator';
            app.Hyperlink = uihyperlink(app.GridLayout12);
            app.Hyperlink.URL = 'https://github.com/David-Rey/SpecGUI/tree/main';
            app.Hyperlink.Text = {'Help'; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.UIAxes_landcape = uiaxes(app.GridLayout4);
            title(app.UIAxes_landcape, 'Landscape')
            xlabel(app.UIAxes_landcape, 'Phase Shift (nm)')
            ylabel(app.UIAxes_landcape, 'Scale Factor')
            zlabel(app.UIAxes_landcape, 'Z')
            app.UIAxes_thomson = uiaxes(app.GridLayout4);
            title(app.UIAxes_thomson, 'Thomson Plot')
            xlabel(app.UIAxes_thomson, 'Wavelength (nm)')
            ylabel(app.UIAxes_thomson, 'Intensity ')
            zlabel(app.UIAxes_thomson, 'Z')
            app.UIAxes_overlay = uiaxes(app.GridLayout4);
            title(app.UIAxes_overlay, 'Generated and Real Data Overlay')
            xlabel(app.UIAxes_overlay, 'Wavelength (nm)')
            ylabel(app.UIAxes_overlay, 'Intensity ')
            zlabel(app.UIAxes_overlay, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimizationGUIV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUpFun)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end