classdef volumeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        VolumeLabelNumber              matlab.ui.control.Label
        VolumeLabel                    matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        CalculateVolumeButton          matlab.ui.control.Button
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        CylinderVolumeCalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.CalculateVolumeButtonPushed()
        end

        % Button pushed function: CalculateVolumeButton
        function CalculateVolumeButtonPushed(app, event)
            R = app.RadiusEditField.Value;
            H = app.HeightEditField.Value;
            V = pi*R^2*H;
            app.VolumeLabelNumber.Text = string(V);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            updateVolume(app.mainapp, app.VolumeLabelNumber.Text)
            app.mainapp.VolumeCalculatorButton.Enable = 'on';
            app.mainapp.TabGroup.SelectedTab = app.mainapp.TabGroup.Children(1);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.mainapp)
                app.mainapp.VolumeCalculatorButton.Enable = 'on';
            end
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CylinderVolumeCalculatorLabel = uilabel(app.GridLayout);
            app.CylinderVolumeCalculatorLabel.Text = 'Cylinder Volume Calculator';
            app.RadiusEditFieldLabel = uilabel(app.GridLayout);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.RadiusEditField.Value = 0.4;
            app.HeightEditFieldLabel = uilabel(app.GridLayout);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.HeightEditField.Value = 1.8;
            app.CalculateVolumeButton = uibutton(app.GridLayout, 'push');
            app.CalculateVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVolumeButtonPushed, true);
            app.CalculateVolumeButton.Text = 'Calculate Volume';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.VolumeLabel = uilabel(app.GridLayout);
            app.VolumeLabel.Text = 'Volume';
            app.VolumeLabelNumber = uilabel(app.GridLayout);
            app.VolumeLabelNumber.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volumeCalculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end